classdef DatViewer_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Dat_Viewer                 matlab.ui.Figure
        FigurePanelControl         matlab.ui.container.Panel
        rPanelNumberSelection      matlab.ui.control.DropDown
        RPanelsLabel               matlab.ui.control.Label
        tPanelNumberSelection      matlab.ui.control.DropDown
        TPanelsLabel               matlab.ui.control.Label
        VerticalCursorButton       matlab.ui.control.StateButton
        GridControlPanel           matlab.ui.container.Panel
        DeleteVariablesButton      matlab.ui.control.Button
        ScaleFactorButton          matlab.ui.control.StateButton
        Degree2RadianButton        matlab.ui.control.StateButton
        Radian2DegreeButton        matlab.ui.control.StateButton
        SourceCBiasEditField       matlab.ui.control.NumericEditField
        SourceCBiasEditFieldLabel  matlab.ui.control.Label
        SourceDBiasEditField       matlab.ui.control.NumericEditField
        SourceDBiasEditFieldLabel  matlab.ui.control.Label
        SourceBBiasEditField       matlab.ui.control.NumericEditField
        SourceBBiasEditFieldLabel  matlab.ui.control.Label
        SourceABiasEditField       matlab.ui.control.NumericEditField
        SourceABiasEditFieldLabel  matlab.ui.control.Label
        ClearConversionButton      matlab.ui.control.Button
        VariableDirectoryPanel     matlab.ui.container.Panel
        VariableList               matlab.ui.control.ListBox
        VariableListSearch         matlab.ui.control.EditField
        SearchEditFieldLabel       matlab.ui.control.Label
        VariableListDropdown       matlab.ui.control.DropDown
        VariablesFromLabel         matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        PlotButton                 matlab.ui.control.Button
        Src4Label                  matlab.ui.control.Label
        Src3Label                  matlab.ui.control.Label
        Src2Label                  matlab.ui.control.Label
        Src1Label                  matlab.ui.control.Label
        Src4                       matlab.ui.control.Button
        Src3                       matlab.ui.control.Button
        Src2                       matlab.ui.control.Button
        Src1                       matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        TimePlot                   matlab.ui.container.Tab
        Grid_t12                   matlab.ui.control.Table
        Grid_t11                   matlab.ui.control.Table
        Grid_t10                   matlab.ui.control.Table
        Grid_t9                    matlab.ui.control.Table
        Grid_t8                    matlab.ui.control.Table
        Grid_t7                    matlab.ui.control.Table
        Grid_t6                    matlab.ui.control.Table
        Grid_t5                    matlab.ui.control.Table
        Grid_t4                    matlab.ui.control.Table
        Grid_t3                    matlab.ui.control.Table
        Grid_t2                    matlab.ui.control.Table
        Grid_t1                    matlab.ui.control.Table
        RegularPlot                matlab.ui.container.Tab
        Grid_r12                   matlab.ui.control.Table
        Grid_r11                   matlab.ui.control.Table
        Grid_r10                   matlab.ui.control.Table
        Grid_r9                    matlab.ui.control.Table
        Grid_r8                    matlab.ui.control.Table
        Grid_r7                    matlab.ui.control.Table
        Grid_r6                    matlab.ui.control.Table
        Grid_r5                    matlab.ui.control.Table
        Grid_r4                    matlab.ui.control.Table
        Grid_r3                    matlab.ui.control.Table
        Grid_r2                    matlab.ui.control.Table
        Grid_r1                    matlab.ui.control.Table
    end

    
    properties (Constant = true, Access = private)
        NumberGrids = 12 % Number of available Grid
        sourceNames = ["Src1", "Src2", "Src3", "Src4"];
    end
    
    properties( Access = public )
        d DatViewer
        OriginalVariableList
    end
    
    properties (Access = private)
        LoadedSource (1,4) = false(1,4);% Description
        selectedVariable
        conversion_name string
        str_format = {'%.6e','%.6f'};
        selected_plot = [];
        selected_grid = [];
        selected_cells = [];

        grid_type = ["Grid_t","Grid_r"];
        rplot_y_grid_offset = 6;
    end

    methods (Access = private)
        
        function select_data(app,source_idx)
            % Select data source. If the data source is loaded, update the
            % source's label
            app.d.importNewSource("",source_idx);
            if app.d.th(source_idx).file_name ~= ""
                app.(app.sourceNames(source_idx)+"Label").Text = app.d.th(source_idx).file_name;
                app.LoadedSource(source_idx) = true;
            end
        end
        
        function update_directory(app,source_idx)
            
            % If empty, show the variables already loaded
            if isempty(source_idx) && any(app.LoadedSource)
                source_idx = find(app.LoadedSource);
                source_idx = source_idx(1);
            end
            
            % Make sure at least there is a source
            if ~isempty(source_idx)
                app.OriginalVariableList = natsort(...
                    [app.d.th(source_idx).AvailableVariablesList(:);...
                     app.d.th(source_idx).derivedData_names]);
                app.VariableList.Items = app.OriginalVariableList;
            end

        end

        function update_grid_selection(app,plot_id,grid_id,indices)
            row_ids = indices(:,1);
            n_selections = size(indices,1);
            if ~isempty(app.selected_plot) && ~isempty(app.selected_grid) && app.selected_grid ~= grid_id
                app.(app.grid_type(plot_id)+app.selected_grid).Selection = [];
            end
            app.selected_plot = plot_id;
            app.selected_grid = grid_id;
            app.selected_cells = row_ids;
            % only 1 cell selection is allowed to add variable
            if n_selections == 1
                app.paste_variable(plot_id,grid_id,row_ids);
            end

            % check for conversion
            app.add_conversion(plot_id,grid_id,row_ids);

        end

        function paste_variable(app,plot_id,grid_id,cell_id)
            
            if ~isempty(app.selectedVariable)
                var_name = [app.VariableListDropdown.Value,' - ',app.selectedVariable{1}];
                app.(app.grid_type(plot_id)+grid_id).Data{cell_id} = var_name;
                
                % After adding the variable, reset all the selection
                app.(app.grid_type(plot_id)+grid_id).Selection = [];
                app.selected_plot = [];
                app.selected_grid = [];
                app.selected_cells = [];
                app.selectedVariable = [];
                app.VariableList.Value = {};
            end

        end

        function clear_variables(app)

            if ~isempty(app.selected_plot)
                app.(app.grid_type(app.selected_plot)+app.selected_grid).Data(app.selected_cells) =...
                    repmat({''},length(app.selected_cells),1);
                app.(app.grid_type(app.selected_plot)+app.selected_grid).Selection = [];
                app.selected_plot = [];
                app.selected_grid = [];
                app.selected_cells = [];
            end

        end

        function add_conversion(app,plot_id,grid_id,row_ids)

            if app.conversion_name ~= ""
                conversion_added = false;
                % iterate through selected rows
                for i = 1:length(row_ids)
                    % if there is a variable, add conversion factor;
                    if ~strcmp(app.(app.grid_type(plot_id)+grid_id).Data{row_ids(i)},'')
                        conversion_added = true;
                        % check for existing conversion in the string
                        existing_conversion = regexp(app.(app.grid_type(plot_id)+grid_id).Data{row_ids(i)},...
                                  "\[\w*(\.\d+)?((e|E)(-|+)?\d+)?\]",'match');

                        % add one if conversion doesn't exist
                        if isempty(existing_conversion)
                            var_name = [app.(app.grid_type(plot_id)+grid_id).Data{row_ids(i)}, ' ', app.conversion_name{1}];
                            app.(app.grid_type(plot_id)+grid_id).Data{row_ids(i)} = var_name;
                        else
                            % Otherwise, replace the conversion with a new
                            % one
                            if ~strcmp(existing_conversion,app.conversion_name{1})
                                var_name = regexprep(app.(app.grid_type(plot_id)+grid_id).Data{row_ids(i)},...
                                  "\[\w*(\.\d+)?((e|E)(-|+)?\d+)?\]",app.conversion_name);
                                app.(app.grid_type(plot_id)+grid_id).Data{row_ids(i)} = var_name;
                            end
                        end
                        

                    end
                end

                % After adding the conversion, reset all the selection
                if conversion_added
                    if app.Radian2DegreeButton.Value
                        app.Radian2DegreeButton.Value = false;
                    elseif app.Degree2RadianButton.Value
                        app.Degree2RadianButton.Value = false;
                    elseif app.ScaleFactorButton.Value
                        app.ScaleFactorButton.Text = 'S.F.';
                        app.ScaleFactorButton.Value = false;
                    end
                    % Reset selection and conversion
                    app.conversion_name = "";
                    app.(app.grid_type(plot_id)+grid_id).Selection = [];
                    app.selected_plot = [];
                    app.selected_grid = [];
                    app.selected_cells = [];
                end
            end
        end

        function clear_conversion(app,plot_id,grid_id,row_ids)

            conversion_removed = false;
            % iterate through selected rows
            for i = 1:length(row_ids)
                % if there is a variable
                if ~strcmp(app.(app.grid_type(plot_id)+grid_id).Data{row_ids(i)},'')

                    % check for existing conversion in the string
                    existing_conversion = regexp(app.(app.grid_type(plot_id)+grid_id).Data{row_ids(i)},...
                              "\[\w*(\.\d+)?((e|E)(-|+)?\d+)?\]",'match');
                    if ~isempty(existing_conversion)
                        conversion_removed = true;
                        % Otherwise, replace the conversion with a new one
                        if ~strcmp(existing_conversion,app.conversion_name{1})
                            var_name = regexprep(app.(app.grid_type(plot_id)+grid_id).Data{row_ids(i)},...
                              "\s\[\w*(\.\d+)?((e|E)(-|+)?\d+)?\]",'');
                            app.(app.grid_type(plot_id)+grid_id).Data{row_ids(i)} = var_name;
                        end
                    end
                end
            end

            % Reset selection
            if conversion_removed
                app.(app.grid_type(plot_id)+grid_id).Selection = [];
                app.selected_plot = [];
                app.selected_grid = [];
                app.selected_cells = [];
            end
            
        end

        function uitable_key_fcn(app,plot_id,grid_id,key)

            switch key
                case "delete"
                    app.clear_variables()
                case "backspace"
                    app.clear_variables()
            end

        end

        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataManager)
            
            % Set up Rows for all Grid
            for igrid_type = app.grid_type
                for grid_num = 1:app.NumberGrids
                    app.(igrid_type+grid_num).Data = {'';'';'';'';'';''};
                end
            end
        
            %-------- Initialize Data Manager
            % If dataManager is empty or not the right class, initialize one
            if isempty(dataManager) || ~isa(dataManager,'DatViewer')
                app.d = DatViewer();
            else
                % Parse the existing data
                app.d = dataManager;
                for i = 1:app.d.Nsource

                    % Load sources' infos
                    if app.d.th(i).file_name ~= ""
                        source_idx = double(app.d.th(i).source_index);
                        app.(app.sourceNames(source_idx)+"Label").Text = app.d.th(i).file_name;
                        app.LoadedSource(source_idx) = true;
                    end
                end
                app.update_directory([]);
            end
            

        end

        % Close request function: Dat_Viewer
        function Dat_ViewerCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.d.datamanager_please_help();
        end

        % Button pushed function: Src1, Src2, Src3, Src4
        function Src1ButtonPushed(app, event)
            source_id = double(string(regexp(event.Source.Text,'\d','match')));
            app.select_data(source_id);
            app.update_directory(source_id);
        end

        % Value changed function: Radian2DegreeButton
        function Radian2DegreeButtonValueChanged(app, event)
            value = app.Radian2DegreeButton.Value;
            if value == true
                if app.Degree2RadianButton.Value
                    app.Degree2RadianButton.Value = false;
                elseif app.ScaleFactorButton.Value
                    app.ScaleFactorButton.Text = "S.F.";
                    app.ScaleFactorButton.Value = false;
                end
                app.conversion_name = "[R2D]";
                if ~isempty(app.selected_grid)
                    app.add_conversion(app.selected_plot,app.selected_grid,app.selected_cells)
                end
            else
                app.conversion_name = "";
            end

        end

        % Value changed function: Degree2RadianButton
        function Degree2RadianButtonValueChanged(app, event)
            value = app.Degree2RadianButton.Value;
            if value == true
                if app.Radian2DegreeButton.Value
                    app.Radian2DegreeButton.Value = false;
                elseif app.ScaleFactorButton.Value
                    app.ScaleFactorButton.Text = "S.F.";
                    app.ScaleFactorButton.Value = false;
                end
                app.conversion_name = "[D2R]";
                if ~isempty(app.selected_grid)
                    app.add_conversion(app.selected_plot,app.selected_grid,app.selected_cells)
                end
            else
                app.conversion_name = "";
            end

        end

        % Value changed function: ScaleFactorButton
        function ScaleFactorButtonValueChanged(app, event)
            value = app.ScaleFactorButton.Value;
            if value == true
                if app.Degree2RadianButton.Value
                    app.Degree2RadianButton.Value = false;
                elseif app.Radian2DegreeButton.Value
                    app.Radian2DegreeButton.Value = false;
                end
                answer = inputdlg('Enter a scalar scale factor:',...
                                  'Scale Factor Input',[1 30],{'1'});

                % evaluate answer
                try
                    scale_factor = eval(answer{1});
                    if abs(scale_factor) > 999.9999 || abs(scale_factor) < 0.001
                        i = 1;
                    else
                        i = 2;
                    end
                    conversion_val = string(num2str(scale_factor,app.str_format{i}));
                    app.conversion_name = "["+conversion_val+"]";
                    app.ScaleFactorButton.Text = "S.F. " + conversion_val;
                    if ~isempty(app.selected_grid)
                        app.add_conversion(app.selected_plot,app.selected_grid,app.selected_cells)
                    end
                catch
                    warning("Scale factor is not numerical");
                    app.ScaleFactorButton.Value = false;
                end

            else
                app.ScaleFactorButton.Text = "S.F.";
                app.conversion_name = "";
            end

        end

        % Button pushed function: ClearConversionButton
        function ClearConversionButtonPushed(app, event)
            if ~isempty(app.selected_grid)
                app.clear_conversion(app.selected_plot,app.selected_grid,app.selected_cells)
            end
        end

        % Value changed function: VariableListDropdown
        function VariableListDropdownValueChanged(app, event)
            value = string(app.VariableListDropdown.Value);
            source_idx = double(string(regexp(value,'\d','match')));
            app.update_directory(source_idx);

            
        end

        % Value changing function: VariableListSearch
        function VariableListSearchValueChanging(app, event)
            changingValue = strtrim(string(event.Value));
            if changingValue == ""
                app.VariableList.Items = app.OriginalVariableList;
            else
                idx = contains(app.OriginalVariableList,changingValue);
                app.VariableList.Items = app.OriginalVariableList(idx);
            end
            
        end

        % Value changed function: VariableList
        function VariableListValueChanged(app, event)
            value = string(app.VariableList.Value);
            app.selectedVariable = value;
            if ~isempty(app.selected_cells) &&...
                    length(app.selected_cells) == 1
                app.paste_variable(app.selected_plot,app.selected_grid,app.selected_cells)
            end
        end

        % Cell selection callback: Grid_r1, Grid_r10, Grid_r11, Grid_r12, 
        % Grid_r2, Grid_r3, Grid_r4, Grid_r5, Grid_r6, Grid_r7, Grid_r8, 
        % Grid_r9, Grid_t1, Grid_t10, Grid_t11, Grid_t12, Grid_t2, 
        % Grid_t3, Grid_t4, Grid_t5, Grid_t6, Grid_t7, Grid_t8, Grid_t9
        function Grid_t1CellSelection(app, event)

            % Find Grid ID
            tabObj = event.Source.Parent;
            gridName = string(event.Source.ColumnName{1});
            grid_id = double(string(regexp(gridName,'\d+','match')));

            % Assign Plot ID
            if strcmp(tabObj.Title,'Time Plot')
                plot_id = 1;
            else
                plot_id = 2;
                % Offset Grid ID if it is for Y Variable
                if contains(gridName,"Y-Variable")
                    grid_id = grid_id + app.rplot_y_grid_offset;
                end
                
            end
            
            indices = event.Indices;
            app.update_grid_selection(plot_id,grid_id,indices);
        end

        % Key press function: Grid_r1, Grid_r10, Grid_r11, Grid_r12, 
        % Grid_r2, Grid_r3, Grid_r4, Grid_r5, Grid_r6, Grid_r7, Grid_r8, 
        % Grid_r9, Grid_t1, Grid_t10, Grid_t11, Grid_t12, Grid_t2, 
        % Grid_t3, Grid_t4, Grid_t5, Grid_t6, Grid_t7, Grid_t8, Grid_t9
        function Grid_t1KeyPress(app, event)
            
            % Find Grid ID
            tabObj = event.Source.Parent;
            gridName = string(event.Source.ColumnName{1});
            grid_id = double(string(regexp(gridName,'\d+','match')));

            % Assign Plot ID
            if strcmp(tabObj.Title,'Time Plot')
                plot_id = 1;
            else
                plot_id = 2;
                % Offset Grid ID if it is for Y Variable
                if contains(gridName,"Y-Variable")
                    grid_id = grid_id + app.rplot_y_grid_offset;
                end
                
            end

            key = string(event.Key);
            app.uitable_key_fcn(plot_id,grid_id,key);
            
        end

        % Value changed function: VerticalCursorButton
        function VerticalCursorButtonValueChanged(app, event)
            value = app.VerticalCursorButton.Value;
            if value
                app.d.pt.vertical_cursor('on');
            else
                app.d.pt.vertical_cursor('off');
            end
        end

        % Button pushed function: DeleteVariablesButton
        function DeleteVariablesButtonPushed(app, event)
            app.clear_variables()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Dat_Viewer = uifigure('Visible', 'off');
            app.Dat_Viewer.Name = 'DatViewer GUI';
            app.Dat_Viewer.CloseRequestFcn = createCallbackFcn(app, @Dat_ViewerCloseRequest, true);
            app.TabGroup = uitabgroup(app.Dat_Viewer);
            app.TimePlot = uitab(app.TabGroup);
            app.TimePlot.Title = 'Time Plot';
            app.Grid_t1 = uitable(app.TimePlot);
            app.Grid_t1.ColumnName = {'Grid 1'};
            app.Grid_t1.RowName = {};
            app.Grid_t1.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_t1.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_t2 = uitable(app.TimePlot);
            app.Grid_t2.ColumnName = {'Grid 2'};
            app.Grid_t2.RowName = {};
            app.Grid_t2.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_t2.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_t3 = uitable(app.TimePlot);
            app.Grid_t3.ColumnName = {'Grid 3'};
            app.Grid_t3.RowName = {};
            app.Grid_t3.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_t3.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_t4 = uitable(app.TimePlot);
            app.Grid_t4.ColumnName = {'Grid 4'};
            app.Grid_t4.RowName = {};
            app.Grid_t4.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_t4.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_t5 = uitable(app.TimePlot);
            app.Grid_t5.ColumnName = {'Grid 5'};
            app.Grid_t5.RowName = {};
            app.Grid_t5.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_t5.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_t6 = uitable(app.TimePlot);
            app.Grid_t6.ColumnName = {'Grid 6'};
            app.Grid_t6.RowName = {};
            app.Grid_t6.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_t6.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_t7 = uitable(app.TimePlot);
            app.Grid_t7.ColumnName = {'Grid 7'};
            app.Grid_t7.RowName = {};
            app.Grid_t7.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_t7.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_t8 = uitable(app.TimePlot);
            app.Grid_t8.ColumnName = {'Grid 8'};
            app.Grid_t8.RowName = {};
            app.Grid_t8.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_t8.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_t9 = uitable(app.TimePlot);
            app.Grid_t9.ColumnName = {'Grid 9'};
            app.Grid_t9.RowName = {};
            app.Grid_t9.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_t9.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_t10 = uitable(app.TimePlot);
            app.Grid_t10.ColumnName = {'Grid 10'};
            app.Grid_t10.RowName = {};
            app.Grid_t10.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_t10.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_t11 = uitable(app.TimePlot);
            app.Grid_t11.ColumnName = {'Grid 11'};
            app.Grid_t11.RowName = {};
            app.Grid_t11.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_t11.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_t12 = uitable(app.TimePlot);
            app.Grid_t12.ColumnName = {'Grid 12'};
            app.Grid_t12.RowName = {};
            app.Grid_t12.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_t12.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.RegularPlot = uitab(app.TabGroup);
            app.RegularPlot.Title = 'Regular Plot';
            app.Grid_r1 = uitable(app.RegularPlot);
            app.Grid_r1.ColumnName = {'Grid 1: X-Variable'};
            app.Grid_r1.RowName = {};
            app.Grid_r1.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_r1.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_r2 = uitable(app.RegularPlot);
            app.Grid_r2.ColumnName = {'Grid 2: X-Variable'};
            app.Grid_r2.RowName = {};
            app.Grid_r2.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_r2.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_r3 = uitable(app.RegularPlot);
            app.Grid_r3.ColumnName = {'Grid 3: X-Variable'};
            app.Grid_r3.RowName = {};
            app.Grid_r3.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_r3.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_r4 = uitable(app.RegularPlot);
            app.Grid_r4.ColumnName = {'Grid 4: X-Variable'};
            app.Grid_r4.RowName = {};
            app.Grid_r4.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_r4.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_r5 = uitable(app.RegularPlot);
            app.Grid_r5.ColumnName = {'Grid 5: X-Variable'};
            app.Grid_r5.RowName = {};
            app.Grid_r5.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_r5.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_r6 = uitable(app.RegularPlot);
            app.Grid_r6.ColumnName = {'Grid 6: X-Variable'};
            app.Grid_r6.RowName = {};
            app.Grid_r6.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_r6.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_r7 = uitable(app.RegularPlot);
            app.Grid_r7.ColumnName = {'Grid 1: Y-Variable'};
            app.Grid_r7.RowName = {};
            app.Grid_r7.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_r7.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_r8 = uitable(app.RegularPlot);
            app.Grid_r8.ColumnName = {'Grid 2: Y-Variable'};
            app.Grid_r8.RowName = {};
            app.Grid_r8.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_r8.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_r9 = uitable(app.RegularPlot);
            app.Grid_r9.ColumnName = {'Grid 3: Y-Variable'};
            app.Grid_r9.RowName = {};
            app.Grid_r9.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_r9.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_r10 = uitable(app.RegularPlot);
            app.Grid_r10.ColumnName = {'Grid 4: Y-Variable'};
            app.Grid_r10.RowName = {};
            app.Grid_r10.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_r10.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_r11 = uitable(app.RegularPlot);
            app.Grid_r11.ColumnName = {'Grid 5: Y-Variable'};
            app.Grid_r11.RowName = {};
            app.Grid_r11.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_r11.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Grid_r12 = uitable(app.RegularPlot);
            app.Grid_r12.ColumnName = {'Grid 6: Y-Variable'};
            app.Grid_r12.RowName = {};
            app.Grid_r12.CellSelectionCallback = createCallbackFcn(app, @Grid_t1CellSelection, true);
            app.Grid_r12.KeyPressFcn = createCallbackFcn(app, @Grid_t1KeyPress, true);
            app.Panel = uipanel(app.Dat_Viewer);
            app.Panel.Title = 'Panel';
            app.Src1 = uibutton(app.Panel, 'push');
            app.Src1.ButtonPushedFcn = createCallbackFcn(app, @Src1ButtonPushed, true);
            app.Src1.Text = 'Source 1';
            app.Src2 = uibutton(app.Panel, 'push');
            app.Src2.ButtonPushedFcn = createCallbackFcn(app, @Src1ButtonPushed, true);
            app.Src2.Text = 'Source 2';
            app.Src3 = uibutton(app.Panel, 'push');
            app.Src3.ButtonPushedFcn = createCallbackFcn(app, @Src1ButtonPushed, true);
            app.Src3.Text = 'Source 3';
            app.Src4 = uibutton(app.Panel, 'push');
            app.Src4.ButtonPushedFcn = createCallbackFcn(app, @Src1ButtonPushed, true);
            app.Src4.Text = 'Source 4';
            app.Src1Label = uilabel(app.Panel);
            app.Src1Label.Text = '';
            app.Src2Label = uilabel(app.Panel);
            app.Src2Label.Text = '';
            app.Src3Label = uilabel(app.Panel);
            app.Src3Label.Text = '';
            app.Src4Label = uilabel(app.Panel);
            app.Src4Label.Text = '';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.VariableDirectoryPanel = uipanel(app.Dat_Viewer);
            app.VariableDirectoryPanel.Title = 'Variable Directory';
            app.VariablesFromLabel = uilabel(app.VariableDirectoryPanel);
            app.VariablesFromLabel.Text = 'Variables From';
            app.VariableListDropdown = uidropdown(app.VariableDirectoryPanel);
            app.VariableListDropdown.Items = {'Source 1', 'Source 2', 'Source 3', 'Source 4'};
            app.VariableListDropdown.ValueChangedFcn = createCallbackFcn(app, @VariableListDropdownValueChanged, true);
            app.VariableListDropdown.Value = 'Source 1';
            app.SearchEditFieldLabel = uilabel(app.VariableDirectoryPanel);
            app.SearchEditFieldLabel.Text = 'Search';
            app.VariableListSearch = uieditfield(app.VariableDirectoryPanel, 'text');
            app.VariableListSearch.ValueChangingFcn = createCallbackFcn(app, @VariableListSearchValueChanging, true);
            app.VariableList = uilistbox(app.VariableDirectoryPanel);
            app.VariableList.Items = {};
            app.VariableList.ValueChangedFcn = createCallbackFcn(app, @VariableListValueChanged, true);
            app.VariableList.Value = {};
            app.GridControlPanel = uipanel(app.Dat_Viewer);
            app.GridControlPanel.Title = 'Grid Control';
            app.ClearConversionButton = uibutton(app.GridControlPanel, 'push');
            app.ClearConversionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearConversionButtonPushed, true);
            app.ClearConversionButton.Text = 'Clear Conversion';
            app.SourceABiasEditFieldLabel = uilabel(app.GridControlPanel);
            app.SourceABiasEditFieldLabel.Text = 'Source A Bias';
            app.SourceABiasEditField = uieditfield(app.GridControlPanel, 'numeric');
            app.SourceBBiasEditFieldLabel = uilabel(app.GridControlPanel);
            app.SourceBBiasEditFieldLabel.Text = 'Source B Bias';
            app.SourceBBiasEditField = uieditfield(app.GridControlPanel, 'numeric');
            app.SourceDBiasEditFieldLabel = uilabel(app.GridControlPanel);
            app.SourceDBiasEditFieldLabel.Text = 'Source D Bias';
            app.SourceDBiasEditField = uieditfield(app.GridControlPanel, 'numeric');
            app.SourceCBiasEditFieldLabel = uilabel(app.GridControlPanel);
            app.SourceCBiasEditFieldLabel.Text = 'Source C Bias';
            app.SourceCBiasEditField = uieditfield(app.GridControlPanel, 'numeric');
            app.Radian2DegreeButton = uibutton(app.GridControlPanel, 'state');
            app.Radian2DegreeButton.ValueChangedFcn = createCallbackFcn(app, @Radian2DegreeButtonValueChanged, true);
            app.Radian2DegreeButton.Text = 'Radian 2 Degree';
            app.Degree2RadianButton = uibutton(app.GridControlPanel, 'state');
            app.Degree2RadianButton.ValueChangedFcn = createCallbackFcn(app, @Degree2RadianButtonValueChanged, true);
            app.Degree2RadianButton.Text = 'Degree 2 Radian';
            app.ScaleFactorButton = uibutton(app.GridControlPanel, 'state');
            app.ScaleFactorButton.ValueChangedFcn = createCallbackFcn(app, @ScaleFactorButtonValueChanged, true);
            app.ScaleFactorButton.Text = 'S.F.';
            app.DeleteVariablesButton = uibutton(app.GridControlPanel, 'push');
            app.DeleteVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteVariablesButtonPushed, true);
            app.DeleteVariablesButton.Text = 'Delete Variable(s)';
            app.FigurePanelControl = uipanel(app.Dat_Viewer);
            app.FigurePanelControl.Title = 'Figure Control';
            app.VerticalCursorButton = uibutton(app.FigurePanelControl, 'state');
            app.VerticalCursorButton.ValueChangedFcn = createCallbackFcn(app, @VerticalCursorButtonValueChanged, true);
            app.VerticalCursorButton.Text = 'Vertical Cursor';
            app.TPanelsLabel = uilabel(app.FigurePanelControl);
            app.TPanelsLabel.Text = 'T-Panels';
            app.tPanelNumberSelection = uidropdown(app.FigurePanelControl);
            app.tPanelNumberSelection.Items = {'1 Panel', '2 Panels', '3 Panels', '4 Panels', '5 Panels', '6 Panels'};
            app.tPanelNumberSelection.Value = '1 Panel';
            app.RPanelsLabel = uilabel(app.FigurePanelControl);
            app.RPanelsLabel.Text = 'R-Panels';
            app.rPanelNumberSelection = uidropdown(app.FigurePanelControl);
            app.rPanelNumberSelection.Items = {'1 Panel', '2 Panels', '3 Panels', '4 Panels', '5 Panels', '6 Panels'};
            app.rPanelNumberSelection.Value = '1 Panel';
            % Show the figure after all components are created
            app.Dat_Viewer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatViewer_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Dat_Viewer)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Dat_Viewer)
        end
    end
end